classdef Receive_Data_And_Ploting_ERROR < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        ConfigurationPanel       matlab.ui.container.Panel
        SerialPortDropDown       matlab.ui.control.DropDown
        SerialPortDropDownLabel  matlab.ui.control.Label
        MeasurementButton        matlab.ui.control.Button
        BitRateKnobLabel         matlab.ui.control.Label
        BitRateKnob              matlab.ui.control.DiscreteKnob
        CellGetNumber            matlab.ui.control.EditField
    end


    properties (Access = private)
        %%    Deff group
        
        Measure_Deactive                   =    0;
        Measure_Active                     =    1;
        
        %%    Constant and Variable
        
        Port                                      % Port Information
        Measure_State
        
        Length_Signal
        
        InputData
        Signal
       
    end

    methods (Access = private)
    
        function Data_Collector_And_Plottin(app)
            
            cla(app.UIAxes);
            
%             app.Signal                     =    zeros(1, app.Length_Signal);
            
            Time_Line                      =    (0: double(app.Length_Signal)...
                                                                      - 1) ./...
                                                double(app.Length_Signal) .* 1;                         
                
            Line_Handle                    =    line(app.UIAxes, 1:length(app.Signal),  ...
                                                                 app.Signal);
            set(Line_Handle, 'Color', 'r', 'LineWidth', 1.25, 'LineStyle', '--')
            set(Line_Handle, 'EraseMode', 'xor')
            
            axis(app.UIAxes, [0, Time_Line(end), -4, 4])
            
            pause(2);
            
            while (1)
                
                %Signal                     =    randn(1, app.Length_Signal);
                
                if (app.Measure_State == app.Measure_Deactive)
                    
                    break;
                    
                end
                
                set(Line_Handle, 'YData', app.Signal);
                
                pause(1);


            end


        end
    
    function Decod_Data = COBS_Decod(app)
    %%  Note: Input_Data    -   Data in format uint8
    %         Decod_Data    -   Data to code COBS uint8
    
    %%  Def group
    
    Input               =   1;
    Output              =   2;
    
    %%  Define Position Point
    
    Previous            =   @(Position)         Position + -1;
    Offset              =   @(Position, Offset) Position + Offset;

    %%  Initial parameter
    
    Length{Input}       =   length(app.Input_Data);
    Decod_Data          =   zeros(1, 2 ^ floor(log2(Length{Input})),...
                            'uint8');
                             
    %%  Program Realization
    
%     Pointer{Input}      =   1;
    Pointer{Output}     =   1;
    
    while(~isempty( app.Input_Data(app.Input_Data ~= 0)) )
        
        Zero_Point      =   int32(app.Input_Data(1));
        
        Input_Data(1)   =   [];
        
        Decod_Data(Pointer{Output}: Offset(Pointer{Output},        ...
                                           Offset(Zero_Point, -2)))...
                        =   Input_Data(1: Previous(Zero_Point));
        
        if (Zero_Point  ~= 255)
            
            Pointer{Output}                                        ...
                        =   Offset(Pointer{Output},                ...
                                   Zero_Point);
        
        else
            
            Pointer{Output}                                        ...
                        =   Offset(Pointer{Output},                ...
                                   Previous(Zero_Point));
                               
        end
        
        Input_Data(1: Previous(Zero_Point))                        ...
                        =   [];

    end
    
            Decod_Data(Previous(Pointer{Output}): end)                     ...
                        =   [];
            
        end
        
        function Read_Data(app)
            
            if (app.Port.BytesAvailable)
            
                app.InputData(end + 1)     =    fread(app.Port, 1, 'uint8');
            
                if (app.InputData(end) == 0)
                
                    app.Signal             =    typecast(                    ...
                                                    COBS_Decode(app),      ...
                                                        'single');
                                                    
                    
                    Data_Collector_And_Plottin(app);
                
                end
                
            end
            
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function Startup_Collback(app)
            
            %%    Закрытие порта, на случай непредвиденных обстоятельств
            close all
            delete(instrfindall);
                        
            Port_List = instrhwinfo('serial');
            
            if (~isempty(Port_List.AvailableSerialPorts))
                
                app.SerialPortDropDown.Items                                  ...
                                           =     Port_List.AvailableSerialPorts;
                                                                                                                     
            end
            
            app.Measure_State              =     app.Measure_Deactive;
            
        end

        % Button pushed function: MeasurementButton
        function Mesuarments_Collback(app, event)
            
            if (app.Measure_State == app.Measure_Deactive)
                
                app.Port                    =    serial(app.SerialPortDropDown...
                                                                          .Value);
                app.Measure_State           =    app.Measure_Active; 
                
                app.MeasurementButton.BackgroundColor                        ...
                                            =    [0, 1, 0];
                
                app.Length_Signal           =    int32(                      ...
                                                 str2double(                 ...
                                                     app.CellGetNumber.Value));
                
                
                app.Port.BaudRate           =    str2double(app.BitRateKnob.Value);
                app.Port.BytesAvailableFcnCount    ...
                                            =    1;
                app.Port.BytesAvailableFcnMode     ...
                                            =    'byte';
                app.Port.BytesAvailableFcn         ...
                                            =    @(~,~)app.Read_Data;
                
                app.SerialPortDropDown.BackgroundColor                       ...
                                            =    [0, 1, 0];
                                        
                fopen(app.Port);
               
            else
                
                app.Measure_State           =    app.Measure_Deactive;
                
                app.MeasurementButton.BackgroundColor                        ...
                                            =    [1, 0, 0];
                
                stopasync(app.Port);
                fclose(app.Port);
                
                app.SerialPortDropDown.BackgroundColor                       ...
                                            =    [1, 0, 0];
                                            
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time, t, sec');
            ylabel(app.UIAxes, 'Acceleration, g, m_{/_{sec^2}}');
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration Panel';
            app.SerialPortDropDown = uidropdown(app.ConfigurationPanel);
            app.SerialPortDropDown.Items = {'No Ports'};
            app.SerialPortDropDown.Value = 'No Ports';
            app.SerialPortDropDownLabel = uilabel(app.ConfigurationPanel);
            app.SerialPortDropDownLabel.Text = 'Serial Port';
            app.MeasurementButton = uibutton(app.ConfigurationPanel, 'push');
            app.MeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @Mesuarments_Collback, true);
            app.MeasurementButton.Text = 'Measurement';
            app.BitRateKnobLabel = uilabel(app.ConfigurationPanel);
            app.BitRateKnobLabel.Text = 'Bit Rate';
            app.BitRateKnob = uiknob(app.ConfigurationPanel, 'discrete');
            app.BitRateKnob.Items = {'7200', '14400', '28800', '57600', '115200'};
            app.BitRateKnob.Value = '7200';
            app.CellGetNumber = uieditfield(app.ConfigurationPanel, 'text');
            app.CellGetNumber.Value = 'Number Discrete';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Receive_Data_And_Ploting_ERROR()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Startup_Collback)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end